classdef ReaderResume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        EntertheBodyofyourResumeCoverLetterLabel  matlab.ui.control.Label
        AnalyzeResumeButton         matlab.ui.control.StateButton
        Label_2                     matlab.ui.control.Label
        representingonestandarddeviationfromthemeanLabel  matlab.ui.control.Label
        AnalyzeCoverLetterButton    matlab.ui.control.StateButton
        Pleaseallowapproximately20secondforcalculationsLabel  matlab.ui.control.Label
        ClearRevisionHistoryButton  matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Value changed function: AnalyzeCoverLetterButton
        function AnalyzeCoverLetterButtonValueChanged(app, event)
            value = app.AnalyzeCoverLetterButton.Value;
            
            avgc = 5.03328998138377*10^3;
            devc = 1.105053363784018*10^3;
            alabama1c = 4.077253406250000*10^3;
            bu1c = 3.574715555555556*10^3;
            columbia1c = 6.081518115942029*10^3;
            columbia2c = 5.438538690476191*10^3;
            oklahoma1c = 3.435125*10^3;
            oklahoma2c = 4.535967741935484*10^3;
            oklahoma3c = 5.330620253164557*10^3;
            oklahoma4c = 2.959537549407115*10^3;
            rutgers1c = 6.38815625*10^3; 
            umich1 = 7.29065625*10^3;
            umich2 = 4.684930434782608*10^3;
            tufts1c = 4.583293023255814 * 10^3;
            tufts2c = 6.082891191709845 * 10^3;
            tufts3c = 5.968279329608939 * 10^3;
            tufts4c = 5.309061946902655 * 10^3;
            upenn1c = 5.979286624203822*10^3;
            upenn2c = 5.02978685365854 * 10^3;
            uva1c = 5.009631097560976 * 10^3;
            uva2c = 5.098060498220641 * 10^3;
            wisco1c = 3.808489583333334 * 10^3;
            
            coverArray = [alabama1c,bu1c,columbia1c,columbia2c,oklahoma1c,...
                oklahoma2c,oklahoma3c,oklahoma4c,rutgers1c,umich1,umich2,tufts1c,...
                tufts2c,tufts3c,tufts4c,upenn1c,upenn2c,uva1c,uva2c,wisco1c];
            
            if value
                 text = app.TextArea.Value;
                 stringy = char(text); 
                 fid1 = fopen('userfile.txt', 'w');
                 fprintf(fid1, '%s', stringy);
                 fclose(fid1);
                 usercell = getCellArray('userfile.txt');
                 score = getScore(usercell,'top100k.xlsx');
                 fid2 = fopen('revisionhistory.txt','a');
                 fprintf(fid2, 'Cover Letter Score: %f\n', score);
                 fclose(fid2);
                 
                 plotuniqueness(coverArray, score, avgc, devc, 'Cover Letter', 1)
            end
        end

        % Value changed function: AnalyzeResumeButton
        function AnalyzeResumeButtonValueChanged(app, event)
            value = app.AnalyzeResumeButton.Value;
            avgr = 1.012393575659252 * 10^4;
            devr = 2.352171247504828 * 10^3;
            alabama1r = 1.000853571428571 * 10^4;
            bu1r = 4.467117318435754 * 10^3;
            bu2r = 1.030561194029851 * 10^4;
            columbia1r = 1.046175302663438 * 10^4;
            oklahoma1r = 1.206489175257732 * 10^4;
            oklahoma2r = 8.036412060301507 * 10^3;
            oklahoma3r = 1.239221118012422 * 10^4;
            rutgers1r = 8.286491582491582 * 10^3;
            tufts1r = 9.792319277108434 * 10^3;
            tufts2r = 8.052654135338345 * 10^3;
            tufts3r = 1.33003461538462 * 10^4;
            tufts4r = 9.855634703196347 * 10^3;
            umich1r = 9.603693215339234 * 10^3;
            upenn1r = 8.417454545454546 * 10^3;
            usc1r = 1.213964423076923 * 10^4;
            usc2r = 1.49317482608696 * 10^4;
            usc3r = 1.04560136986304 * 10^4;
            wisco1r = 9.658312796208531 * 10^3;
            
            resumeArray = [alabama1r,bu1r,bu2r,columbia1r,oklahoma1r,oklahoma2r,...
                oklahoma3r,rutgers1r,tufts1r,tufts2r,tufts3r,tufts4r,umich1r,...
                upenn1r,usc1r,usc2r,usc3r,wisco1r];                
            
            if value
                 text = app.TextArea.Value;
                 stringy = char(text); 
                 fid1 = fopen('userfile.txt', 'w');
                 fprintf(fid1, '%s', stringy);
                 fclose(fid1);
                 usercell = getCellArray('userfile.txt');
                 score = getScore(usercell,'top100k.xlsx');
                 fid2 = fopen('revisionhistory.txt','a');
                 fprintf(fid2, 'Resume Score: %f\n', score);
                 fclose(fid2);
                 
                 plotuniqueness(resumeArray, score, avgr, devr, 'Resume', 1)
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)
            %unused
        end

        % Value changed function: ClearRevisionHistoryButton
        function ClearRevisionHistoryButtonValueChanged(app, event)
            value = app.ClearRevisionHistoryButton.Value;
            if value
                fid = fopen('revisionhistory.txt','w');
                fclose(fid);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.EntertheBodyofyourResumeCoverLetterLabel = uilabel(app.UIFigure);
            app.EntertheBodyofyourResumeCoverLetterLabel.Text = 'Enter the Body of your Resume/Cover Letter';
            app.AnalyzeResumeButton = uibutton(app.UIFigure, 'state');
            app.AnalyzeResumeButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzeResumeButtonValueChanged, true);
            app.AnalyzeResumeButton.Text = 'Analyze Resume';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'After "Analyze" is pressed, you will see a graph with a marked average uniqueness score and error bars ';
            app.representingonestandarddeviationfromthemeanLabel = uilabel(app.UIFigure);
            app.representingonestandarddeviationfromthemeanLabel.Text = 'representing one standard deviation from the mean. It is recommended that your file fall within this range.';
            app.AnalyzeCoverLetterButton = uibutton(app.UIFigure, 'state');
            app.AnalyzeCoverLetterButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzeCoverLetterButtonValueChanged, true);
            app.AnalyzeCoverLetterButton.Text = 'Analyze Cover Letter';
            app.Pleaseallowapproximately20secondforcalculationsLabel = uilabel(app.UIFigure);
            app.Pleaseallowapproximately20secondforcalculationsLabel.Text = 'Please allow approximately 20 second for calculations.';
            app.ClearRevisionHistoryButton = uibutton(app.UIFigure, 'state');
            app.ClearRevisionHistoryButton.ValueChangedFcn = createCallbackFcn(app, @ClearRevisionHistoryButtonValueChanged, true);
            app.ClearRevisionHistoryButton.Text = 'Clear Revision History';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ReaderResume

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end